classdef Humor_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        AddRowButton                    matlab.ui.control.Button
        EnterJokesLabel                 matlab.ui.control.Label
        ReadxlsxButton                  matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        JokeNumberSlider                matlab.ui.control.Slider
        StopButton                      matlab.ui.control.Button
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        PlotButton                      matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        OptionsTab                      matlab.ui.container.Tab
        AudienceSizeEditFieldLabel      matlab.ui.control.Label
        AudienceSizeField               matlab.ui.control.NumericEditField
        MediationDropDownLabel          matlab.ui.control.Label
        MediationDropDown               matlab.ui.control.DropDown
        NumberofComediansSwitchLabel    matlab.ui.control.Label
        NumberofComediansSwitch         matlab.ui.control.ToggleSwitch
        ComedianEditFieldLabel          matlab.ui.control.Label
        ComedianEditField               matlab.ui.control.EditField
        PlacementTypeDropDownLabel      matlab.ui.control.Label
        PlacementTypeDropDown           matlab.ui.control.DropDown
        IntensityTunerKnobLabel         matlab.ui.control.Label
        IntensityTunerKnob              matlab.ui.control.Knob
        TimedSwitchLabel                matlab.ui.control.Label
        TimedSwitch                     matlab.ui.control.Switch
        FramesPerSecondEditFieldLabel   matlab.ui.control.Label
        FramesPerSecondEditField        matlab.ui.control.NumericEditField
        SimulationTitleEditFieldLabel   matlab.ui.control.Label
        SimulationTitleEditField        matlab.ui.control.EditField
        NewWindowonPlayCheckBox         matlab.ui.control.CheckBox
        AdvancedSettingsTab             matlab.ui.container.Tab
        SizeOfAudienceMembersKnobLabel  matlab.ui.control.Label
        SizeOfAudienceMembersKnob       matlab.ui.control.Knob
        HowToTab                        matlab.ui.container.Tab
        TitleLabel                      matlab.ui.control.Label
        OpenNewWindowButton             matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
    end

     %clear classes;
     %a = py.importlib.import_module('humor_python');
     %py.importlib.reload(a);
    
    properties (Access = private)
        PopUpAppRef
        
        sim_timer
        joke_counter
        
        comedian_table_storage
        
        intensity_const = 1/33;
        
        cur_drag_index
        joke_times
        sim
        
        history
        
        current_joke_slider_index
        
        audience_marker_size
    end
    
    properties (Access = public)
        intensity_mod 
        punchlines
    end
    
    methods (Access = private)
        %passes timer info in here so I'll probably need this eventually
        function step(app, ~, ~)
            if size(app.history, 2) > app.joke_counter
                %if history exists already, use it
                app.joke_counter = app.joke_counter + 1;
                app.plot_history(app.joke_counter)
                app.JokeNumberSlider.Value = app.joke_counter;
            else
                app.sim.step(app.joke_counter, app.intensity_mod)
                app.joke_counter = app.joke_counter + 1;
                
                app.replot()
                
                app.JokeNumberSlider.Value = app.joke_counter;
                
                app.history = [app.history, copy(get(app.UIAxes, 'children'))];
                
                if size(app.punchlines, 1) == size(app.UITable.Data, 1)
                    %I don't like how this is done
                    app.UIAxes.Title.String = string(app.punchlines(app.joke_counter, :).(1)) + ' (' + string(app.UITable.Data.('Type')(app.joke_counter)) + ')';
                end
                
                if app.joke_counter+1 > size(app.UITable.Data, 1)
                    stop(app.sim_timer)
                    set(app.PlayButton, 'enable', 'on');
                end
            end
        end
        
        function plot_history(app, index)
            for i= 1:size(app.history, 1)
                if strcmp(app.history(i, index).Type, 'line')
                    app.UIAxes.Children(i).XData = app.history(i, index).XData;
                    app.UIAxes.Children(i).YData = app.history(i, index).YData;
                elseif strcmp(app.history(i, index).Type, 'text')
                    app.UIAxes.Children(i).Position = app.history(i, index).Position;
                end
            try
                app.UIAxes.Title.String = string(app.punchlines(index, :).(1));
            end
            end
        end

        function replot(app)
            %draw audience members
            pos_a = double(app.sim.pos_audience);
            plot(app.UIAxes, pos_a(:, 1), pos_a(:, 2), "Marker","o", 'MarkerSize',app.audience_marker_size, 'LineStyle',"none", "MarkerFaceColor",'b');
            hold(app.UIAxes)
            
            %draw the left-out people
            pos_lo = double(app.sim.left_out);
            plot(app.UIAxes, pos_lo(:, 1), pos_lo(:, 2), "Marker","o", 'MarkerSize',app.audience_marker_size+1, 'LineStyle',"none", "MarkerFaceColor","#FFFF00");
            
            %draw people
            pos_p = double(app.sim.pos_people);
            plot(app.UIAxes, pos_p(:, 1), pos_p(:, 2), "Marker","o", 'MarkerSize',8, 'LineStyle',"none", "MarkerFaceColor","#000000");
            
            %draw the butt over the people
            pos_butt = double(app.sim.pos_cur_butt);
            plot(app.UIAxes, pos_butt(1), pos_butt(2), "Marker","o", 'MarkerSize',10, 'LineStyle',"none", "MarkerFaceColor","r");
            
            %draw the comedian over the people (or butt if self-deprecating)
            pos_com = double(app.sim.pos_cur_com);
            plot(app.UIAxes, pos_com(1), pos_com(2), "Marker","o", 'MarkerSize',10, 'LineStyle',"none", "MarkerFaceColor","g");
            
            dy = app.sim.a_sqrt/50;
            for i = 1:size(app.sim.people, 2)
               text(app.UIAxes, pos_p(i, 1), pos_p(i, 2) + dy, string(app.sim.people(i).pop()))
            end
            hold(app.UIAxes,'off')
        end
        
        function starting_plot(app)
            pos_a = double(app.sim.pos_audience);
            plot(app.UIAxes, pos_a(:, 1), pos_a(:, 2), "Marker","o", 'MarkerSize', app.audience_marker_size, 'LineStyle',"none", "MarkerFaceColor","b");
            hold(app.UIAxes)
            
            pos_p = double(app.sim.pos_people);
            plot(app.UIAxes, pos_p(:, 1), pos_p(:, 2), "Marker","o", 'MarkerSize',8, 'LineStyle',"none", "MarkerFaceColor","#000000",'hittest','on','buttondownfcn',@app.clickmarker);
            
            
            dy = app.sim.a_sqrt/50;
            for i = 1:size(app.sim.people, 2)
               text(app.UIAxes, pos_p(i, 1), pos_p(i, 2) + dy, string(app.sim.people(i).pop()))
            end
            
            hold(app.UIAxes,'off')
        end
    
        
        function clickmarker(app,src,~)
            set(ancestor(src,'figure'),'windowbuttonmotionfcn',{@app.dragmarker, src})
            set(ancestor(src,'figure'),'windowbuttonupfcn',@app.stopdragging)
        end
        
        function stopdragging(app,fig,~)
            set(fig,'windowbuttonmotionfcn','')
            set(fig,'windowbuttonupfcn','')
            app.cur_drag_index = 0;
            app.sim.set_pos_people(py.list(app.UIAxes.Children(end-1).XData), py.list(app.UIAxes.Children(end-1).YData)) 
        end
                
        function dragmarker(app,~,~,~)
            h1=app.UIAxes;
            coords=get(h1,'currentpoint');
             
             %get x and y data from the child containing the people
            x=h1.Children(end-1).XData;
            y=h1.Children(end-1).YData;
             
%             coords = app.UIAxes.CurrentPoint;
%             pos_p = double(app.sim.pos_people);
%             x = pos_p(:, 1);
%             y = pos_p(:, 2);

            %check which data point has the smallest distance to the dragged point
            if app.cur_drag_index == 0
                x_diff=abs(x-coords(1,1, 1));
                y_diff=abs(y-coords(1,2, 1));
                [value index]=min(x_diff+y_diff);
                app.cur_drag_index = index;
            end
            
            %create new x and y data and exchange coords for the dragged point
            x_new=x;
            y_new=y;
            x_new(app.cur_drag_index)=coords(1,1,1);
            y_new(app.cur_drag_index)=coords(1,2,1);
            
            %update plot
            %app.sim.set_pos_people([x_new, y_new]) 
            %app.starting_plot()
            set(h1.Children(end-1),'xdata',x_new,'ydata',y_new);
            set(h1.Children(end-app.cur_drag_index-1),'position',[x_new(app.cur_drag_index), y_new(app.cur_drag_index) + app.sim.a_sqrt/50, 0]);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            pathToSpeech = fileparts(which('humor_python.py'));
            if count(py.sys.path, pathToSpeech) == 0
                insert(py.sys.path, int32(0), pathToSpeech)
            end
            
            app.UITable.Data = array2table(strings(1, numel(app.UITable.ColumnName)));
            app.UITable.Data.Properties.VariableNames = app.UITable.ColumnName;
            
            app.intensity_mod = app.intensity_const;
            
            app.joke_counter = 0;
            app.cur_drag_index = 0;
            
            app.audience_marker_size = 3;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            writetable(app.UITable.Data, 'matrix.txt', 'WriteVariableNames', false)

            app.sim = py.humor_python.Sim(int32(app.AudienceSizeField.Value), app.NumberofComediansSwitch.Value, app.MediationDropDown.Value, app.ComedianEditField.Value, app.PlacementTypeDropDown.Value);
            
            app.UIAxes.XLim = [0 int64(app.sim.a_sqrt)];
            app.UIAxes.YLim = [0 int64(app.sim.a_sqrt)];
            
            if size(app.UITable.Data, 1) == 1
                app.JokeNumberSlider.Limits = [0, size(app.UITable.Data, 1)];
            else
                app.JokeNumberSlider.Limits = [1, size(app.UITable.Data, 1)];
            end
            
            app.JokeNumberSlider.MajorTicks = 1:5:size(app.UITable.Data, 1);
            app.joke_counter = 0;
            app.JokeNumberSlider.Value = 1;
            app.current_joke_slider_index = 0;
            
            app.starting_plot()
            
            app.history = [];
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            %copies the last row, doesn't add an empty one
            app.UITable.Data = [app.UITable.Data; app.UITable.Data(end, :)];
        end

        % Value changed function: NumberofComediansSwitch
        function NumberofComediansSwitchValueChanged(app, event)
            value = app.NumberofComediansSwitch.Value;
            if strcmp(value, 'Multiple')
                app.ComedianEditField.Visible = 'off';
                app.ComedianEditFieldLabel.Visible = 'off';
                app.UITable.ColumnName = ['Comedian' app.UITable.ColumnName'];
                app.UITable.Data(:, 2:end+1) = app.UITable.Data;
                try
                    app.UITable.Data(:, 1) = app.comedian_table_storage;
                catch
                    app.UITable.Data(:, 1) = repmat({''}, size(app.UITable.Data, 1), 1);
                end
                app.PlacementTypeDropDown.Items = [{'Random'}, {'Flyting'}];%app.PlacementTypeDropDown.Items(1);
            else
                app.ComedianEditField.Visible = 'on';
                app.ComedianEditFieldLabel.Visible = 'on';
                app.UITable.ColumnName = app.UITable.ColumnName(2:end);
                app.comedian_table_storage = app.UITable.Data(:, 1);
                app.UITable.Data = app.UITable.Data(:, 2:end);
                app.PlacementTypeDropDown.Items = [{'Random'}, {'Polygon'}];
                
            end
        end

        % Button pushed function: ReadxlsxButton
        function ReadxlsxButtonPushed(app, event)
           %UNFINISHED
            t = readtable(app.FileNameEditField.Value, 'Sheet', 1, 'Format','auto');
            if strcmp(app.NumberofComediansSwitch.Value, 'Multiple')
                app.UITable.Data = t(11:end, 2:6);
            else
                app.UITable.Data = t(11:end, 3:6);
            end
            app.joke_times = t(11:end, 1);
            app.punchlines = t(11:end, 7);
            app.UITable.Data.Properties.VariableNames = app.UITable.ColumnName;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            app.sim_timer = timer(...
            'ExecutionMode', 'fixedRate', ...    
            'BusyMode', 'queue', ...    
            'Period', 1/app.FramesPerSecondEditField.Value, ...                   
            'TimerFcn', @app.step); 
            
            app.UIAxes.XLim = [0 int64(app.sim.a_sqrt)];
            app.UIAxes.YLim = [0 int64(app.sim.a_sqrt)];
            
            app.joke_counter = 0;
            app.JokeNumberSlider.Value = 1;
            app.current_joke_slider_index = 0;
            
            start(app.sim_timer);
            set(app.PlayButton, 'enable', 'off');

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.sim_timer);
            app.joke_counter = 0;
            set(app.PlayButton, 'enable', 'on');
        end

        % Value changing function: JokeNumberSlider
        function JokeNumberSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.current_joke_slider_index == 0
                app.current_joke_slider_index = round(changingValue);
            elseif app.current_joke_slider_index ~= round(changingValue)
                app.current_joke_slider_index = round(changingValue);
                if size(app.history, 2) > 1 && strcmp(app.sim_timer.Running, 'off')
                    app.plot_history(app.current_joke_slider_index)
                end
            end
            
        end

        % Value changing function: IntensityTunerKnob
        function IntensityTunerKnobValueChanging(app, event)
            value = round(event.Value);
            if value == 0
                app.intensity_mod = app.intensity_const;
            elseif value < 0
                app.intensity_mod = app.intensity_const / abs(value);
            elseif value > 0
                app.intensity_mod = value * app.intensity_const;
            end
        end

        % Value changing function: SimulationTitleEditField
        function SimulationTitleEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.TitleLabel.Text = changingValue;
            set(app.TitleLabel, 'visible', 'on')
        end

        % Value changed function: NewWindowonPlayCheckBox
        function NewWindowonPlayCheckBoxValueChanged(app, event)
            value = app.NewWindowonPlayCheckBox.Value;
            if value == true
                app.PlayButton.Visible = false;
                app.StopButton.Visible = false;
                app.ResetButton.Visible = false;
                app.OpenNewWindowButton.Visible = true;
            else
                app.OpenNewWindowButton.Visible = false;
                app.PlayButton.Visible = true;
                app.StopButton.Visible = true;
                app.ResetButton.Visible = true;
            end
        end

        % Button pushed function: OpenNewWindowButton
        function OpenNewWindowButtonPushed(app, event)
            app.PopUpAppRef = PopUpApp(app, app.sim, app.FramesPerSecondEditField.Value);
        end

        % Value changing function: SizeOfAudienceMembersKnob
        function SizeOfAudienceMembersKnobValueChanging(app, event)
            changingValue = event.Value;
            app.audience_marker_size = changingValue;
            app.starting_plot()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            stop(app.sim_timer);
            app.joke_counter = 0;
            
            set(app.PlayButton, 'enable', 'on');
            app.JokeNumberSlider.Value = 1;
            app.plot_history(1)
            app.history = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Punchlines')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Type'; 'Butt'; 'Intensity'; 'Crescendo Count'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.AddRowButton = uibutton(app.UIFigure, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = {'Add Row'; ''};
            app.EnterJokesLabel = uilabel(app.UIFigure);
            app.EnterJokesLabel.Text = 'Enter Jokes:';
            app.ReadxlsxButton = uibutton(app.UIFigure, 'push');
            app.ReadxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @ReadxlsxButtonPushed, true);
            app.ReadxlsxButton.Text = 'Read xlsx';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.JokeNumberSlider = uislider(app.UIFigure);
            app.JokeNumberSlider.Limits = [0 1];
            app.JokeNumberSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.JokeNumberSlider.ValueChangingFcn = createCallbackFcn(app, @JokeNumberSliderValueChanging, true);
            app.JokeNumberSlider.Value = 1;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Value = 'sheet1.xlsx';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.AudienceSizeEditFieldLabel = uilabel(app.OptionsTab);
            app.AudienceSizeEditFieldLabel.Text = 'Audience Size';
            app.AudienceSizeField = uieditfield(app.OptionsTab, 'numeric');
            app.AudienceSizeField.Limits = [0 Inf];
            app.AudienceSizeField.Value = 100;
            app.MediationDropDownLabel = uilabel(app.OptionsTab);
            app.MediationDropDownLabel.Text = 'Mediation';
            app.MediationDropDown = uidropdown(app.OptionsTab);
            app.MediationDropDown.Items = {'Face To Face', 'Single', 'Laugh Track', 'Mediated Face to Face'};
            app.MediationDropDown.Value = 'Face To Face';
            app.NumberofComediansSwitchLabel = uilabel(app.OptionsTab);
            app.NumberofComediansSwitchLabel.Text = 'Number of Comedians';
            app.NumberofComediansSwitch = uiswitch(app.OptionsTab, 'toggle');
            app.NumberofComediansSwitch.Items = {'One Comedian', 'Multiple'};
            app.NumberofComediansSwitch.ValueChangedFcn = createCallbackFcn(app, @NumberofComediansSwitchValueChanged, true);
            app.NumberofComediansSwitch.Value = 'One Comedian';
            app.ComedianEditFieldLabel = uilabel(app.OptionsTab);
            app.ComedianEditFieldLabel.Text = 'Comedian';
            app.ComedianEditField = uieditfield(app.OptionsTab, 'text');
            app.PlacementTypeDropDownLabel = uilabel(app.OptionsTab);
            app.PlacementTypeDropDownLabel.Text = 'Placement Type';
            app.PlacementTypeDropDown = uidropdown(app.OptionsTab);
            app.PlacementTypeDropDown.Items = {'Random', 'Polygon'};
            app.PlacementTypeDropDown.Value = 'Random';
            app.IntensityTunerKnobLabel = uilabel(app.OptionsTab);
            app.IntensityTunerKnobLabel.Text = 'Intensity Tuner';
            app.IntensityTunerKnob = uiknob(app.OptionsTab, 'continuous');
            app.IntensityTunerKnob.Limits = [-10 10];
            app.IntensityTunerKnob.ValueChangingFcn = createCallbackFcn(app, @IntensityTunerKnobValueChanging, true);
            app.TimedSwitchLabel = uilabel(app.OptionsTab);
            app.TimedSwitchLabel.Text = 'Timed';
            app.TimedSwitch = uiswitch(app.OptionsTab, 'slider');
            app.FramesPerSecondEditFieldLabel = uilabel(app.OptionsTab);
            app.FramesPerSecondEditFieldLabel.Text = 'Frames Per Second';
            app.FramesPerSecondEditField = uieditfield(app.OptionsTab, 'numeric');
            app.FramesPerSecondEditField.Limits = [0 Inf];
            app.FramesPerSecondEditField.Value = 2;
            app.SimulationTitleEditFieldLabel = uilabel(app.OptionsTab);
            app.SimulationTitleEditFieldLabel.Text = 'Simulation Title';
            app.SimulationTitleEditField = uieditfield(app.OptionsTab, 'text');
            app.SimulationTitleEditField.ValueChangingFcn = createCallbackFcn(app, @SimulationTitleEditFieldValueChanging, true);
            app.NewWindowonPlayCheckBox = uicheckbox(app.OptionsTab);
            app.NewWindowonPlayCheckBox.ValueChangedFcn = createCallbackFcn(app, @NewWindowonPlayCheckBoxValueChanged, true);
            app.NewWindowonPlayCheckBox.Text = 'New Window on Play';
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.SizeOfAudienceMembersKnobLabel = uilabel(app.AdvancedSettingsTab);
            app.SizeOfAudienceMembersKnobLabel.Text = 'Size Of Audience Members';
            app.SizeOfAudienceMembersKnob = uiknob(app.AdvancedSettingsTab, 'continuous');
            app.SizeOfAudienceMembersKnob.Limits = [0.1 10];
            app.SizeOfAudienceMembersKnob.ValueChangingFcn = createCallbackFcn(app, @SizeOfAudienceMembersKnobValueChanging, true);
            app.SizeOfAudienceMembersKnob.Value = 3;
            app.HowToTab = uitab(app.TabGroup);
            app.HowToTab.Title = 'How To';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Visible = 'off';
            app.TitleLabel.Text = 'Title Label';
            app.OpenNewWindowButton = uibutton(app.UIFigure, 'push');
            app.OpenNewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpenNewWindowButtonPushed, true);
            app.OpenNewWindowButton.Visible = 'off';
            app.OpenNewWindowButton.Text = 'Open New Window';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Humor_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end