classdef PopUpApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        JokeNumberSlider  matlab.ui.control.Slider
        UIAxes            matlab.ui.control.UIAxes
        PlayButton        matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        TitleLabel        matlab.ui.control.Label
        ResetButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp   % Main app object
        sim
        sim_timer
        joke_counter
        history
        current_joke_slider_index
    end
    
    methods (Access = private)
        
        function step(app, ~, ~)
            if size(app.history, 2) > app.joke_counter
                %if history exists already, use it
                app.joke_counter = app.joke_counter + 1;
                app.plot_history(app.joke_counter)
                app.JokeNumberSlider.Value = app.joke_counter;
            else
                %if history does not exist, go ahead and calculate
                app.sim.step(app.joke_counter, app.CallingApp.intensity_mod)
                app.joke_counter = app.joke_counter + 1;
                
                app.replot()
                
                app.JokeNumberSlider.Value = app.joke_counter;
                
                app.history = [app.history, copy(get(app.UIAxes, 'children'))];
                
                if size(app.CallingApp.punchlines, 1) == size(app.CallingApp.UITable.Data, 1)
                    %I don't like how this is done
                    app.UIAxes.Title.String = string(app.CallingApp.punchlines(app.joke_counter, :).(1)) + ' (' + string(app.CallingApp.UITable.Data.('Type')(app.joke_counter)) + ')';
                end
                
                if app.joke_counter+1 > size(app.CallingApp.UITable.Data, 1)
                    stop(app.sim_timer)
                    set(app.PlayButton, 'enable', 'on');
                end
            end
        end
        
        
        function replot(app)
            %draw audience members
            pos_a = double(app.sim.pos_audience);
            plot(app.UIAxes, pos_a(:, 1), pos_a(:, 2), "Marker","o", 'MarkerSize',3, 'LineStyle',"none", "MarkerFaceColor",'b');
            hold(app.UIAxes)
            
            %draw the left-out people
            pos_lo = double(app.sim.left_out);
            plot(app.UIAxes, pos_lo(:, 1), pos_lo(:, 2), "Marker","o", 'MarkerSize',4, 'LineStyle',"none", "MarkerFaceColor","#FFFF00");
            
            %draw people
            pos_p = double(app.sim.pos_people);
            plot(app.UIAxes, pos_p(:, 1), pos_p(:, 2), "Marker","o", 'MarkerSize',8, 'LineStyle',"none", "MarkerFaceColor","#000000");
            
            %draw the butt over the people
            pos_butt = double(app.sim.pos_cur_butt);
            plot(app.UIAxes, pos_butt(1), pos_butt(2), "Marker","o", 'MarkerSize',10, 'LineStyle',"none", "MarkerFaceColor","r");
            
            %draw the comedian over the people (or butt if self-deprecating)
            pos_com = double(app.sim.pos_cur_com);
            plot(app.UIAxes, pos_com(1), pos_com(2), "Marker","o", 'MarkerSize',10, 'LineStyle',"none", "MarkerFaceColor","g");
            
            dy = app.sim.a_sqrt/50;
            for i = 1:size(app.sim.people, 2)
               text(app.UIAxes, pos_p(i, 1), pos_p(i, 2) + dy, string(app.sim.people(i).pop()))
            end
            hold(app.UIAxes,'off')
        end


        function plot_history(app, index)
            for i= 1:size(app.history, 1)
                if strcmp(app.history(i, index).Type, 'line')
                    app.UIAxes.Children(i).XData = app.history(i, index).XData;
                    app.UIAxes.Children(i).YData = app.history(i, index).YData;
                elseif strcmp(app.history(i, index).Type, 'text')
                    app.UIAxes.Children(i).Position = app.history(i, index).Position;
                end
            app.UIAxes.Title.String = string(app.CallingApp.punchlines(index, :).(1));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sim, fps)
            app.CallingApp = mainapp;
            app.sim = sim;
            
            app.TitleLabel.Text = app.CallingApp.SimulationTitleEditField.Value;
            
            app.JokeNumberSlider.Limits = [1, size(app.CallingApp.UITable.Data, 1)];
            app.JokeNumberSlider.MajorTicks = 1:5:size(app.CallingApp.UITable.Data, 1);
            app.joke_counter = 0;
            app.JokeNumberSlider.Value = 1;
            app.current_joke_slider_index = 0;
            
            %plot the 
            pos_a = double(app.sim.pos_audience);
            plot(app.UIAxes, pos_a(:, 1), pos_a(:, 2), "Marker","o", 'MarkerSize',3, 'LineStyle',"none", "MarkerFaceColor","b");
            hold(app.UIAxes)
            
            pos_p = double(app.sim.pos_people);
            plot(app.UIAxes, pos_p(:, 1), pos_p(:, 2), "Marker","o", 'MarkerSize',8, 'LineStyle',"none", "MarkerFaceColor","#000000")%,'hittest','on','buttondownfcn',@app.clickmarker); 
            
            dy = app.sim.a_sqrt/50;
            for i = 1:size(app.sim.people, 2)
               text(app.UIAxes, pos_p(i, 1), pos_p(i, 2) + dy, string(app.sim.people(i).pop()))
            end
            
            hold(app.UIAxes,'off')
            
            app.sim_timer = timer(...
                'ExecutionMode', 'fixedRate', ...
                'BusyMode', 'queue', ...    
                'Period', 1/fps, ...                     
                'TimerFcn', @app.step); 
            
            app.history = [];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.CallingApp.OptionsButton.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.UIAxes.XLim = [0 int64(app.sim.a_sqrt)];
            app.UIAxes.YLim = [0 int64(app.sim.a_sqrt)];
            
            start(app.sim_timer);
            set(app.PlayButton, 'enable', 'off');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.sim_timer);
            app.joke_counter = 0;
            set(app.PlayButton, 'enable', 'on');
        end

        % Value changing function: JokeNumberSlider
        function JokeNumberSliderValueChanging(app, event)
            changingValue = event.Value;
            if app.current_joke_slider_index == 0
                app.current_joke_slider_index = round(changingValue);
            elseif app.current_joke_slider_index ~= round(changingValue)
                app.current_joke_slider_index = round(changingValue);
                if size(app.history, 2) > 1 && strcmp(app.sim_timer.Running, 'off')
                    app.plot_history(app.current_joke_slider_index)
                end
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            stop(app.sim_timer);
            app.joke_counter = 0;
            
            set(app.PlayButton, 'enable', 'on');
            app.JokeNumberSlider.Value = 1;
            app.plot_history(1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.JokeNumberSlider = uislider(app.UIFigure);
            app.JokeNumberSlider.ValueChangingFcn = createCallbackFcn(app, @JokeNumberSliderValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Punchlines')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PopUpApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end